classdef desktopApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        searchButton             matlab.ui.control.Button
        connectButton            matlab.ui.control.Button
        DevicesDropDownLabel     matlab.ui.control.Label
        DevicesDropDown          matlab.ui.control.DropDown
        UltraSonicSwitchLabel    matlab.ui.control.Label
        UltraSonicSwitch         matlab.ui.control.Switch
        LeftButton               matlab.ui.control.Button
        RightButton              matlab.ui.control.Button
        ForwardButton            matlab.ui.control.Button
        BackButton               matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        s40                      matlab.ui.control.Button
        s80                      matlab.ui.control.Button
        FullspeedButton          matlab.ui.control.Button
        LineFollowerSwitchLabel  matlab.ui.control.Label
        LineFollowerSwitch       matlab.ui.control.Switch
        dissconncetButton        matlab.ui.control.Button
        notconnectedLampLabel    matlab.ui.control.Label
        notconnectedLamp         matlab.ui.control.Lamp
    end


    properties (Access = public)
        Property % Description
    end

    properties (Access = private)
        b % bluetooth 
        channel %communication channel
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DevicesDropDown.Visible='off';
            app.DevicesDropDownLabel.Visible='off';
            app.notconnectedLamp.Color=[1 0 0];

        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            %connect to car
            dev=app.DevicesDropDown.Value;
            app.b = Bluetooth(dev,1);
            fopen(app.b);
            %make connection lamb green
            app.notconnectedLamp.Color=[0 1 0];
            app.notconnectedLampLabel.Text='Connected';

        end

        % Button pushed function: searchButton
        function searchButtonPushed(app, event)

            %search devices
            mydevices = instrhwinfo('Bluetooth');
            app.DevicesDropDown.Items=mydevices.RemoteNames;
            app.DevicesDropDown.Visible='on';

            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            fwrite(app.b, 'F');
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            fwrite(app.b, 'L');
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            fwrite(app.b, 'R');
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            fwrite(app.b, 'B');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            fwrite(app.b, 'S');
        end

        % Button pushed function: s40
        function s40ButtonPushed(app, event)
            fwrite(app.b, '2');
        end

        % Button pushed function: s80
        function s80ButtonPushed(app, event)
            fwrite(app.b, '3');
        end

        % Button pushed function: FullspeedButton
        function FullspeedButtonPushed(app, event)
            fwrite(app.b, '4');
        end

        % Button pushed function: dissconncetButton
        function dissconncetButtonPushed(app, event)
            fclose(app.b);
        end

        % Value changed function: UltraSonicSwitch
        function UltraSonicSwitchValueChanged(app, event)
            value = app.UltraSonicSwitch.Value;
            switch value
                case 'On'
                    fwrite(app.b, 'U');
                case 'Off'
                    fwrite(app.b, 'u');
            end
        end

        % Value changed function: LineFollowerSwitch
        function LineFollowerSwitchValueChanged(app, event)
            value = app.LineFollowerSwitch.Value;
            switch value
                case 'On'
                    fwrite(app.b, 'A');
                case 'Off'
                    fwrite(app.b, 'S');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.searchButton = uibutton(app.UIFigure, 'push');
            app.searchButton.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton.Text = 'search';
            app.connectButton = uibutton(app.UIFigure, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.DevicesDropDownLabel = uilabel(app.UIFigure);
            app.DevicesDropDownLabel.Text = 'Devices';
            app.DevicesDropDown = uidropdown(app.UIFigure);
            app.UltraSonicSwitchLabel = uilabel(app.UIFigure);
            app.UltraSonicSwitchLabel.Text = 'UltraSonic';
            app.UltraSonicSwitch = uiswitch(app.UIFigure, 'slider');
            app.UltraSonicSwitch.ValueChangedFcn = createCallbackFcn(app, @UltraSonicSwitchValueChanged, true);
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.s40 = uibutton(app.UIFigure, 'push');
            app.s40.ButtonPushedFcn = createCallbackFcn(app, @s40ButtonPushed, true);
            app.s40.Text = '40%';
            app.s80 = uibutton(app.UIFigure, 'push');
            app.s80.ButtonPushedFcn = createCallbackFcn(app, @s80ButtonPushed, true);
            app.s80.Text = '80%';
            app.FullspeedButton = uibutton(app.UIFigure, 'push');
            app.FullspeedButton.ButtonPushedFcn = createCallbackFcn(app, @FullspeedButtonPushed, true);
            app.FullspeedButton.Text = 'Full speed';
            app.LineFollowerSwitchLabel = uilabel(app.UIFigure);
            app.LineFollowerSwitchLabel.Text = 'Line Follower';
            app.LineFollowerSwitch = uiswitch(app.UIFigure, 'slider');
            app.LineFollowerSwitch.ValueChangedFcn = createCallbackFcn(app, @LineFollowerSwitchValueChanged, true);
            app.dissconncetButton = uibutton(app.UIFigure, 'push');
            app.dissconncetButton.ButtonPushedFcn = createCallbackFcn(app, @dissconncetButtonPushed, true);
            app.dissconncetButton.Text = 'dissconncet';
            app.notconnectedLampLabel = uilabel(app.UIFigure);
            app.notconnectedLampLabel.Text = 'not connected';
            app.notconnectedLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = desktopApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end