classdef desktopApp2016b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        searchButton                matlab.ui.control.Button
        connectButton               matlab.ui.control.Button
        DevicesDropDownLabel        matlab.ui.control.Label
        DevicesDropDown             matlab.ui.control.DropDown
        UltraSonicSwitchLabel       matlab.ui.control.Label
        UltraSonicSwitch            matlab.ui.control.Switch
        LeftButton                  matlab.ui.control.Button
        RightButton                 matlab.ui.control.Button
        ForwardButton               matlab.ui.control.Button
        BackButton                  matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        s40                         matlab.ui.control.Button
        s80                         matlab.ui.control.Button
        FullspeedButton             matlab.ui.control.Button
        LineFollowerSwitchLabel     matlab.ui.control.Label
        LineFollowerSwitch          matlab.ui.control.Switch
        dissconncetButton           matlab.ui.control.Button
        notconnectedLampLabel       matlab.ui.control.Label
        notconnectedLamp            matlab.ui.control.Lamp
        DisplacementEditFieldLabel  matlab.ui.control.Label
        DisplacementEditField       matlab.ui.control.NumericEditField
        SendDisButton               matlab.ui.control.Button
        AngleEditFieldLabel         matlab.ui.control.Label
        AngleEditField              matlab.ui.control.NumericEditField
        SendAngleButton             matlab.ui.control.Button
        DrawshapesButton            matlab.ui.control.Button
        TuneSpeedEditFieldLabel     matlab.ui.control.Label
        TuneSpeedEditField          matlab.ui.control.NumericEditField
        SendTuneSpeedButton         matlab.ui.control.Button
        SendTuneDistanceButton      matlab.ui.control.Button
        TuneDistanceEditFieldLabel  matlab.ui.control.Label
        TuneDistanceEditField       matlab.ui.control.NumericEditField
        TuneAngleEditFieldLabel     matlab.ui.control.Label
        TuneAngleEditField          matlab.ui.control.NumericEditField
        SendTuneAngleButton         matlab.ui.control.Button
        SendTuneSpeedRatioButton    matlab.ui.control.Button
        SpeedratioEditFieldLabel    matlab.ui.control.Label
        SpeedratioEditField         matlab.ui.control.NumericEditField
        SendTunecircleDelayButton   matlab.ui.control.Button
        CircledelayEditFieldLabel   matlab.ui.control.Label
        CircledelayEditField        matlab.ui.control.NumericEditField
        TuneButton                  matlab.ui.control.Button
        RectangleButton             matlab.ui.control.Button
        CircleButton                matlab.ui.control.Button
        InfinityButton              matlab.ui.control.Button
    end


    properties (Access = public)
        Property % Description
    end

    properties (Access = private)
        b % bluetooth 
        channel %communication channel
        tuflg=0%tune flag
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DevicesDropDown.Visible='off';
            app.DevicesDropDownLabel.Visible='off';
            app.notconnectedLamp.Color=[1 0 0];
            
            app.SendTuneAngleButton.Visible='off';
            app.SendTunecircleDelayButton.Visible='off';
            app.SendTuneDistanceButton.Visible='off';
            app.SendTuneSpeedButton.Visible='off';
            app.SendTuneSpeedRatioButton.Visible='off';
            
            app.TuneAngleEditField.Visible='off';
            app.TuneDistanceEditField.Visible='off';
            app.TuneSpeedEditField.Visible='off';
            app.CircledelayEditField.Visible='off';
            app.SpeedratioEditField.Visible='off';
            
            app.TuneAngleEditFieldLabel.Visible='off';
            app.TuneDistanceEditFieldLabel.Visible='off';
            app.TuneSpeedEditFieldLabel.Visible='off';
            app.CircledelayEditFieldLabel.Visible='off';
            app.SpeedratioEditFieldLabel.Visible='off';

        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            %connect to car
            dev=app.DevicesDropDown.Value;
            app.b = Bluetooth(dev,1);
            fopen(app.b);
            %make connection lamb green
            app.notconnectedLamp.Color=[0 1 0];
            app.notconnectedLampLabel.Text='Connected';

        end

        % Button pushed function: searchButton
        function searchButtonPushed(app, event)

            %search devices
            mydevices = instrhwinfo('Bluetooth');
            app.DevicesDropDown.Items=mydevices.RemoteNames;
            app.DevicesDropDown.Visible='on';

            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            fwrite(app.b, 'F');
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            fwrite(app.b, 'L');
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            fwrite(app.b, 'R');
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            fwrite(app.b, 'B');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            fwrite(app.b, 'S');
        end

        % Button pushed function: s40
        function s40ButtonPushed(app, event)
            fwrite(app.b, '2');
        end

        % Button pushed function: s80
        function s80ButtonPushed(app, event)
            fwrite(app.b, '3');
        end

        % Button pushed function: FullspeedButton
        function FullspeedButtonPushed(app, event)
            fwrite(app.b, '4');
        end

        % Button pushed function: dissconncetButton
        function dissconncetButtonPushed(app, event)
            fclose(app.b);
            app.notconnectedLamp.Color=[1 0 0];
            app.notconnectedLampLabel.Text='not connected';
        end

        % Value changed function: UltraSonicSwitch
        function UltraSonicSwitchValueChanged(app, event)
            value = app.UltraSonicSwitch.Value;
            switch value
                case 'On'
                    fwrite(app.b, 'U');
                case 'Off'
                    fwrite(app.b, 'u');
            end
        end

        % Value changed function: LineFollowerSwitch
        function LineFollowerSwitchValueChanged(app, event)
            value = app.LineFollowerSwitch.Value;
            switch value
                case 'On'
                    fwrite(app.b, 'A');
                case 'Off'
                    fwrite(app.b, 'S');
            end
        end

        % Button pushed function: SendDisButton
        function SendDisButtonPushed(app, event)
            dis=app.DisplacementEditField.Value;
            dis=int2str(dis);
            dis=strcat('D',int2str(length(dis)),dis,'q');
            fwrite(app.b, dis);
        end

        % Button pushed function: SendAngleButton
        function SendAngleButtonPushed(app, event)
            ang=app.AngleEditField.Value;
            ang=int2str(ang);
            ang=strcat('G',int2str(length(ang)),ang,'q');
            fwrite(app.b, ang);
        end

        % Button pushed function: SendTuneSpeedButton
        function SendTuneSpeedButtonPushed(app, event)
            veloc=app.TuneSpeedEditField.Value;
            veloc=int2str(veloc);
            veloc=strcat('T',int2str(length(veloc)),veloc,'q');
            fwrite(app.b, veloc);
        end

        % Button pushed function: SendTuneDistanceButton
        function SendTuneDistanceButtonPushed(app, event)
            d=app.TuneDistanceEditField.Value;
            d=int2str(d);
            d=strcat('d',int2str(length(d)),d,'q');
            fwrite(app.b, d);
        end

        % Button pushed function: SendTuneAngleButton
        function SendTuneAngleButtonPushed(app, event)
            a=app.TuneAngleEditField.Value;
            a=int2str(a);
            a=strcat('a',int2str(length(a)),a,'q');
            fwrite(app.b, a);
        end

        % Button pushed function: SendTuneSpeedRatioButton
        function SendTuneSpeedRatioButtonPushed(app, event)
            sr=app.SpeedratioEditField.Value;
            sr=int2str(sr);
            sr=strcat('r',int2str(length(sr)),sr,'q');
            fwrite(app.b, sr);
        end

        % Button pushed function: SendTunecircleDelayButton
        function SendTunecircleDelayButtonPushed(app, event)
            cd=app.CircledelayEditField.Value;
            cd=int2str(cd);
            cd=strcat('c',int2str(length(cd)),cd,'q');
            fwrite(app.b, cd);
        end

        % Button pushed function: DrawshapesButton
        function DrawshapesButtonPushed(app, event)
            fwrite(app.b, 'h');
        end

        % Button pushed function: TuneButton
        function TuneButtonPushed(app, event)
            if (app.tuflg==1)
                
            app.SendTuneAngleButton.Visible='off';
            app.SendTunecircleDelayButton.Visible='off';
            app.SendTuneDistanceButton.Visible='off';
            app.SendTuneSpeedButton.Visible='off';
            app.SendTuneSpeedRatioButton.Visible='off';
            
            app.TuneAngleEditField.Visible='off';
            app.TuneDistanceEditField.Visible='off';
            app.TuneSpeedEditField.Visible='off';
            app.CircledelayEditField.Visible='off';
            app.SpeedratioEditField.Visible='off';
            
            app.TuneAngleEditFieldLabel.Visible='off';
            app.TuneDistanceEditFieldLabel.Visible='off';
            app.TuneSpeedEditFieldLabel.Visible='off';
            app.CircledelayEditFieldLabel.Visible='off';
            app.SpeedratioEditFieldLabel.Visible='off';
            
            app.tuflg=0;
            else
                
            app.SendTuneAngleButton.Visible='on';
            app.SendTunecircleDelayButton.Visible='on';
            app.SendTuneDistanceButton.Visible='on';
            app.SendTuneSpeedButton.Visible='on';
            app.SendTuneSpeedRatioButton.Visible='on';
            
            app.TuneAngleEditField.Visible='on';
            app.TuneDistanceEditField.Visible='on';
            app.TuneSpeedEditField.Visible='on';
            app.CircledelayEditField.Visible='on';
            app.SpeedratioEditField.Visible='on';
            
            app.TuneAngleEditFieldLabel.Visible='on';
            app.TuneDistanceEditFieldLabel.Visible='on';
            app.TuneSpeedEditFieldLabel.Visible='on';
            app.CircledelayEditFieldLabel.Visible='on';
            app.SpeedratioEditFieldLabel.Visible='on';
            app.tuflg=1;
            end
        end

        % Button pushed function: RectangleButton
        function RectangleButtonPushed(app, event)
            fwrite(app.b, 'o');
        end

        % Button pushed function: CircleButton
        function CircleButtonPushed(app, event)
            fwrite(app.b, 'p');
        end

        % Button pushed function: InfinityButton
        function InfinityButtonPushed(app, event)
            fwrite(app.b, 'q');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.searchButton = uibutton(app.UIFigure, 'push');
            app.searchButton.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton.Text = 'search';
            app.connectButton = uibutton(app.UIFigure, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.DevicesDropDownLabel = uilabel(app.UIFigure);
            app.DevicesDropDownLabel.Text = 'Devices';
            app.DevicesDropDown = uidropdown(app.UIFigure);
            app.UltraSonicSwitchLabel = uilabel(app.UIFigure);
            app.UltraSonicSwitchLabel.Text = 'UltraSonic';
            app.UltraSonicSwitch = uiswitch(app.UIFigure, 'slider');
            app.UltraSonicSwitch.ValueChangedFcn = createCallbackFcn(app, @UltraSonicSwitchValueChanged, true);
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.s40 = uibutton(app.UIFigure, 'push');
            app.s40.ButtonPushedFcn = createCallbackFcn(app, @s40ButtonPushed, true);
            app.s40.Text = '40%';
            app.s80 = uibutton(app.UIFigure, 'push');
            app.s80.ButtonPushedFcn = createCallbackFcn(app, @s80ButtonPushed, true);
            app.s80.Text = '80%';
            app.FullspeedButton = uibutton(app.UIFigure, 'push');
            app.FullspeedButton.ButtonPushedFcn = createCallbackFcn(app, @FullspeedButtonPushed, true);
            app.FullspeedButton.Text = 'Full speed';
            app.LineFollowerSwitchLabel = uilabel(app.UIFigure);
            app.LineFollowerSwitchLabel.Text = 'Line Follower';
            app.LineFollowerSwitch = uiswitch(app.UIFigure, 'slider');
            app.LineFollowerSwitch.ValueChangedFcn = createCallbackFcn(app, @LineFollowerSwitchValueChanged, true);
            app.dissconncetButton = uibutton(app.UIFigure, 'push');
            app.dissconncetButton.ButtonPushedFcn = createCallbackFcn(app, @dissconncetButtonPushed, true);
            app.dissconncetButton.Text = 'dissconncet';
            app.notconnectedLampLabel = uilabel(app.UIFigure);
            app.notconnectedLampLabel.Text = 'not connected';
            app.notconnectedLamp = uilamp(app.UIFigure);
            app.DisplacementEditFieldLabel = uilabel(app.UIFigure);
            app.DisplacementEditFieldLabel.Text = 'Displacement';
            app.DisplacementEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplacementEditField.ValueDisplayFormat = '%.0f';
            app.SendDisButton = uibutton(app.UIFigure, 'push');
            app.SendDisButton.ButtonPushedFcn = createCallbackFcn(app, @SendDisButtonPushed, true);
            app.SendDisButton.Text = 'Send';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.ValueDisplayFormat = '%.0f';
            app.SendAngleButton = uibutton(app.UIFigure, 'push');
            app.SendAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SendAngleButtonPushed, true);
            app.SendAngleButton.Text = 'Send';
            app.DrawshapesButton = uibutton(app.UIFigure, 'push');
            app.DrawshapesButton.ButtonPushedFcn = createCallbackFcn(app, @DrawshapesButtonPushed, true);
            app.DrawshapesButton.Text = 'Draw shapes';
            app.TuneSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.TuneSpeedEditFieldLabel.Text = 'Tune Speed';
            app.TuneSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.TuneSpeedEditField.ValueDisplayFormat = '%.0f';
            app.SendTuneSpeedButton = uibutton(app.UIFigure, 'push');
            app.SendTuneSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SendTuneSpeedButtonPushed, true);
            app.SendTuneSpeedButton.Text = 'Send';
            app.SendTuneDistanceButton = uibutton(app.UIFigure, 'push');
            app.SendTuneDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @SendTuneDistanceButtonPushed, true);
            app.SendTuneDistanceButton.Text = 'Send';
            app.TuneDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.TuneDistanceEditFieldLabel.Text = 'Tune Distance';
            app.TuneDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.TuneDistanceEditField.ValueDisplayFormat = '%.0f';
            app.TuneAngleEditFieldLabel = uilabel(app.UIFigure);
            app.TuneAngleEditFieldLabel.Text = 'Tune Angle';
            app.TuneAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TuneAngleEditField.ValueDisplayFormat = '%.0f';
            app.SendTuneAngleButton = uibutton(app.UIFigure, 'push');
            app.SendTuneAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SendTuneAngleButtonPushed, true);
            app.SendTuneAngleButton.Text = 'Send';
            app.SendTuneSpeedRatioButton = uibutton(app.UIFigure, 'push');
            app.SendTuneSpeedRatioButton.ButtonPushedFcn = createCallbackFcn(app, @SendTuneSpeedRatioButtonPushed, true);
            app.SendTuneSpeedRatioButton.Text = 'Send';
            app.SpeedratioEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedratioEditFieldLabel.Text = 'Speed ratio';
            app.SpeedratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedratioEditField.ValueDisplayFormat = '%.0f';
            app.SendTunecircleDelayButton = uibutton(app.UIFigure, 'push');
            app.SendTunecircleDelayButton.ButtonPushedFcn = createCallbackFcn(app, @SendTunecircleDelayButtonPushed, true);
            app.SendTunecircleDelayButton.Text = 'Send';
            app.CircledelayEditFieldLabel = uilabel(app.UIFigure);
            app.CircledelayEditFieldLabel.Text = 'Circle delay';
            app.CircledelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.CircledelayEditField.ValueDisplayFormat = '%.0f';
            app.TuneButton = uibutton(app.UIFigure, 'push');
            app.TuneButton.ButtonPushedFcn = createCallbackFcn(app, @TuneButtonPushed, true);
            app.TuneButton.Text = 'Tune';
            app.RectangleButton = uibutton(app.UIFigure, 'push');
            app.RectangleButton.ButtonPushedFcn = createCallbackFcn(app, @RectangleButtonPushed, true);
            app.RectangleButton.Text = 'Rectangle';
            app.CircleButton = uibutton(app.UIFigure, 'push');
            app.CircleButton.ButtonPushedFcn = createCallbackFcn(app, @CircleButtonPushed, true);
            app.CircleButton.Text = 'Circle';
            app.InfinityButton = uibutton(app.UIFigure, 'push');
            app.InfinityButton.ButtonPushedFcn = createCallbackFcn(app, @InfinityButtonPushed, true);
            app.InfinityButton.Text = 'Infinity';
        end
    end

    methods (Access = public)

        % Construct app
        function app = desktopApp2016b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end